classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AddprofileButton              matlab.ui.control.Button
        AddanImagetotheProfileButton  matlab.ui.control.Button
        RecognizeButton               matlab.ui.control.Button
        AddImageButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        img_original        % Original image, for face recognition output
        img_recognized      % Image recognized by the alghoritm
        best_match          % best matched image
        matched_profile     % best matched profile
        euclidean_distance  % distance between matched image and the original one
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)
            add_image();
        end

        % Button pushed function: RecognizeButton
        function RecognizeButtonPushed(app, event)
            [                           ...
                app.img_original,       ...
                app.img_recognized,     ...
                app.best_match,         ...
                app.matched_profile,    ...
                app.euclidean_distance  ...
            ] = face_recognition();
        end

        % Button pushed function: AddanImagetotheProfileButton
        function AddanImagetotheProfileButtonPushed(app, event)
            add_image_to_the_profile();
        end

        % Button pushed function: AddprofileButton
        function AddprofileButtonPushed(app, event)
            add_profile();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddImageButton = uibutton(app.UIFigure, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Text = 'Add Image';
            app.RecognizeButton = uibutton(app.UIFigure, 'push');
            app.RecognizeButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeButtonPushed, true);
            app.RecognizeButton.Text = 'Recognize';
            app.AddanImagetotheProfileButton = uibutton(app.UIFigure, 'push');
            app.AddanImagetotheProfileButton.ButtonPushedFcn = createCallbackFcn(app, @AddanImagetotheProfileButtonPushed, true);
            app.AddanImagetotheProfileButton.Text = 'Add an Image to the Profile';
            app.AddprofileButton = uibutton(app.UIFigure, 'push');
            app.AddprofileButton.ButtonPushedFcn = createCallbackFcn(app, @AddprofileButtonPushed, true);
            app.AddprofileButton.Text = 'Add profile';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end